classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        TabGroup               matlab.ui.container.TabGroup       % Abaque ...
        Tab                    matlab.ui.container.Tab            % Abaque ...
        Tab2                   matlab.ui.container.Tab            % Caracté...
        abaque                 matlab.ui.control.UIAxes           % Abaque ...
        LabelNumericEditField2 matlab.ui.control.Label            % a
        a                      matlab.ui.control.NumericEditField % [-Inf Inf]
        calc                   matlab.ui.control.Button           % Calculer
        Panel                  matlab.ui.container.Panel          % Paramètres
        Panel2                 matlab.ui.container.Panel          % Résultats
        LabelNumericEditField5 matlab.ui.control.Label            % n1
        n1                     matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField6 matlab.ui.control.Label            % n2
        n2                     matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField7 matlab.ui.control.Label            % n3
        n3                     matlab.ui.control.NumericEditField % [-Inf Inf]
        Label2                 matlab.ui.control.Label            % lambda
        l                      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label3                 matlab.ui.control.Label            % d
        d                      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label4                 matlab.ui.control.Label           
        Label5                 matlab.ui.control.Label           
        Label6                 matlab.ui.control.Label           
        Label7                 matlab.ui.control.Label           
        Label8                 matlab.ui.control.Label           
        LabelSpinner           matlab.ui.control.Label            % m
        m2                     matlab.ui.control.Spinner          % [0 Inf]
        LabelSpinner2          matlab.ui.control.Label            % m
        m1                     matlab.ui.control.Spinner          % [0 Inf]
        LabelNumericEditField  matlab.ui.control.Label            % Nombre ...
        m3                     matlab.ui.control.NumericEditField % [-Inf Inf]
        Label9                 matlab.ui.control.Label            % Indice ...
        ite                    matlab.ui.control.NumericEditField % [-Inf Inf]
        Label10                matlab.ui.control.Label            % Indice ...
        itm                    matlab.ui.control.NumericEditField % [-Inf Inf]
        Label11                matlab.ui.control.Label            % Confine...
        cte                    matlab.ui.control.NumericEditField % [-Inf Inf]
        Label12                matlab.ui.control.Label            % Confine...
        ctm                    matlab.ui.control.NumericEditField % [-Inf Inf]
    end


    methods (Access = private)
        
        function f1 = abaqueTE(app)
            [x,y] = meshgrid(linspace(0,15,200),linspace(0,1,200));
            TE = 2*x.*sqrt(1-y)-m.*pi-atan(sqrt(y./(1-y)))-atan(sqrt((app.a+y)./(1-y)));
            f1 = contour(x,y,TE, [0,0]);
        end

        function f1 = abaqueTM(app)
            [x,y] = meshgrid(linspace(0,15,200),linspace(0,1,200));
            TM = 2*x*sqrt(1-y)-m*pi-atan(nc^2/ns^2*(sqrt(y/(1-y))))-atan(nc^2/nsp^2*sqrt((app.a+y)/(1-y)));
            f1 = contour(x,y,TM, [0,0]);
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % calc button pushed function
        function calcAbaque(app)
            
        end

        % m2 value changed function
        function m2ValueChanged(app)
            value = app.m2.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Abaque TE/TM';
            app.abaque = uiaxes(app.Tab);
            title(app.abaque, 'Abaque TE/TM');
            xlabel(app.abaque, 'X');
            ylabel(app.abaque, 'Y');
            app.LabelNumericEditField2 = uilabel(app.Tab);
            app.LabelNumericEditField2.Text = 'a';
            app.a = uieditfield(app.Tab, 'numeric');
            app.calc = uibutton(app.Tab, 'push');
            app.calc.ButtonPushedFcn = createCallbackFcn(app, @calcAbaque);
            app.calc.Text = 'Calculer';
            app.LabelSpinner2 = uilabel(app.Tab);
            app.LabelSpinner2.Text = 'm';
            app.m1 = uispinner(app.Tab);
            app.m1.Limits = [0 Inf];
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Caractéristiques';
            app.Panel = uipanel(app.Tab2);
            app.Panel.Title = 'Paramètres';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField5 = uilabel(app.Panel);
            app.LabelNumericEditField5.Text = 'n1';
            app.n1 = uieditfield(app.Panel, 'numeric');
            app.LabelNumericEditField6 = uilabel(app.Panel);
            app.LabelNumericEditField6.Text = 'n2';
            app.n2 = uieditfield(app.Panel, 'numeric');
            app.LabelNumericEditField7 = uilabel(app.Panel);
            app.LabelNumericEditField7.Text = 'n3';
            app.n3 = uieditfield(app.Panel, 'numeric');
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'lambda';
            app.l = uieditfield(app.Panel, 'numeric');
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'd';
            app.d = uieditfield(app.Panel, 'numeric');
            app.LabelSpinner = uilabel(app.Panel);
            app.LabelSpinner.Text = 'm';
            app.m2 = uispinner(app.Panel);
            app.m2.ValueChangedFcn = createCallbackFcn(app, @m2ValueChanged);
            app.m2.Limits = [0 Inf];
            app.Panel2 = uipanel(app.Tab2);
            app.Panel2.Title = 'Résultats';
            app.Panel2.Units = 'pixels';
            app.Label4 = uilabel(app.Panel2);
            app.Label4.Text = '';
            app.Label5 = uilabel(app.Panel2);
            app.Label5.Text = '';
            app.Label6 = uilabel(app.Panel2);
            app.Label6.Text = '';
            app.Label7 = uilabel(app.Panel2);
            app.Label7.Text = '';
            app.Label8 = uilabel(app.Panel2);
            app.Label8.Text = '';
            app.LabelNumericEditField = uilabel(app.Panel2);
            app.LabelNumericEditField.Text = 'Nombre de modes :';
            app.m3 = uieditfield(app.Panel2, 'numeric');
            app.m3.Editable = 'off';
            app.Label10 = uilabel(app.Panel2);
            app.Label10.Text = 'Indice (mode TM) :';
            app.itm = uieditfield(app.Panel2, 'numeric');
            app.itm.Editable = 'off';
            app.Label11 = uilabel(app.Panel2);
            app.Label11.Text = 'Confinement (mode TE) : ';
            app.cte = uieditfield(app.Panel2, 'numeric');
            app.cte.Editable = 'off';
            app.Label12 = uilabel(app.Panel2);
            app.Label12.Text = 'Confinement (mode TM) : ';
            app.ctm = uieditfield(app.Panel2, 'numeric');
            app.ctm.Editable = 'off';
            app.Label9 = uilabel(app.Panel2);
            app.Label9.Text = 'Indice (mode TE) :';
            app.ite = uieditfield(app.Panel2, 'numeric');
            app.ite.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end